classdef Main_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_InterfaceUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        GivetheDataFileandInputParametersHereLabel  matlab.ui.control.Label
        ImportDataFilePanel             matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        InputFileEditFieldLabel         matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        Module1Panel                    matlab.ui.container.Panel
        PEComponentsinTimeseriesButton  matlab.ui.control.Button
        PEComponentsinPlanviewButton    matlab.ui.control.Button
        Module2Panel                    matlab.ui.container.Panel
        FaultStabilityinPlanviewandPlottingButton  matlab.ui.control.Button
        FaultStabilityinTimeseriesandPlottingButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        HeadingLabel                    matlab.ui.control.Label
        ResultPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        stress_pTab                     matlab.ui.container.Tab
        ppdiffusion_pTab                matlab.ui.container.Tab
        ppstressinduced_pTab            matlab.ui.container.Tab
        stress_tTab                     matlab.ui.container.Tab
        ppdiffusion_tTab                matlab.ui.container.Tab
        ppstressinduced_tTab            matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        app1 % Description
        app2
        finalapp3
        finalapp4
    end
    
    methods (Access = public)
        
        function importdata(app,tdata)
          
               uit=uitable(app.stress_tTab,'Data',tdata);
               uit.Position = [50 80 500 600];
               uit.FontWeight = 'bold';
             
        end
         function importdata1(app,tdata1)
          
               uit=uitable(app.ppdiffusion_tTab,'Data',tdata1);
               uit.Position = [50 80 150 600];
               uit.FontWeight = 'bold';        
               
         end
          function importdata2(app,tdata2,tdata3)
      
               uit=uitable(app.ppstressinduced_tTab,'Data',tdata3);
               uit.Position = [50 80 500 600];
               uit.FontWeight = 'bold';
             
        end
         function importdata3(app,tdata4)
           
               uit=uitable(app.stress_pTab,'Data',tdata4);
               uit.Position = [50 20 500 600];
               uit.FontWeight = 'bold';
                
         end
          function importdata4(app,tdata5)
           
               uit=uitable(app.ppdiffusion_pTab,'Data',tdata5);
               uit.Position = [50 20 150 600];
               uit.FontWeight = 'bold';
            
                
          end
           function importdata5(app,tdata6,tdata7)
           
               uit=uitable(app.ppstressinduced_pTab,'Data',tdata7);
               uit.Position = [50 80 500 600];
               uit.FontWeight = 'bold';
                
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
%             center = screenWidth*0.1;
%             bottom = screenHeight*0.1;
%             width = screenWidth*0.7;
%             height = screenHeight*0.6;
%             %drawnow;
%             app.Main_InterfaceUIFigure.Position = [center bottom width height]; 
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path,indx]= uigetfile({'*.*'},'File Selector');
             filename=[path file];
               app.InputFileEditField.Value = filename;
              figure(app.Main_InterfaceUIFigure);
        end

        % Button pushed function: PEComponentsinTimeseriesButton
        function PEComponentsinTimeseriesButtonPushed(app, event)
             app.app2=app2(app);
             global FNAM;
             FNAM=app.InputFileEditField.Value;
            
%             global FNAM;
% %             global Xobs;
% %             global Yobs; 
% %             global Xdisc;
% %             global Ydisc;
% %             global nt;
%            FNAM=app.InputFileEditField.Value;
%            
%            % first m file
%            [pp,tt]=plct_Test(FNAM,dx1,dy1);
%            [pp,tt] =deal(pp,tt);
%            tdata = table(pp,tt);
%            uit=uitable(app.stress_pTab,'Data',tdata);
%            uit.Position = [40 20 350 450];
%             uit.FontWeight = 'bold';
%             
%            %second m file
%             [D2,D3]=plrst_Test();
%             [D2,D3] =deal(D2,D3);
%            tdata1 = table(D2,D3);
%            uit1=uitable(app.ppdiffusion_pTab,'Data',tdata1);
%            uit1.Position = [25 20 450 450];
%             uit1.FontWeight = 'bold';
%             
            %third m file
%             [D4]=simkbt_Test()
% %             [D4] =deal(D4);
% %            tdata2 = table(D4);
%            uit2=uitable(app.stress_tTab,'Data',D4);
%            uit2.Position = [40 20 350 450];
%             uit2.FontWeight = 'bold';
%             
%             %fourth m file
%              [D5]=simkit_Test_1()
%              [D5] =deal(D5);
%              tdata2 = table(D5);
%            uit3=uitable(app.ppdiffusion_tTab,'Data',tdata2);
%            uit3.Position = [40 20 350 450];
%             uit3.FontWeight = 'bold';
%             
            
            %fifth m file
%             [D5]=simkit_Test()
%              [D5] =deal(D5);
%              tdata2 = table(D5);
%            uit4=uitable(app.Computesimkit_TestTab,'Data',tdata2);
%            uit4.Position = [40 20 350 450];
%             uit4.FontWeight = 'bold';
        end

        % Button pushed function: PEComponentsinPlanviewButton
        function PEComponentsinPlanviewButtonPushed(app, event)
            app.app1=app1(app);
          
             global FNAM;
             FNAM=app.InputFileEditField.Value;
             
%             global Xobs;
%             global Yobs;
%             global Xdisc;
%             global Ydisc;
%             global nt;


%             FNAM
%              [D2]=plrs(FNAM);
%              [D2] =deal(D2);
%             tdata = table(D2);
%              uit=uitable(app.stress_pTab,'Data',D2);
%             uit.Position = [40 20 350 450];
%            uit.FontWeight = 'bold';
        end

        % Button pushed function: 
        % FaultStabilityinTimeseriesandPlottingButton
        function FaultStabilityinTimeseriesandPlottingButtonPushed(app, event)
          app.finalapp4=finalapp4(app);
        end

        % Button pushed function: 
        % FaultStabilityinPlanviewandPlottingButton
        function FaultStabilityinPlanviewandPlottingButtonPushed(app, event)
             
            app.finalapp3=finalapp3(app);
            
              
%              global FNAM;
%              FNAM=app.InputFileEditField.Value;
%             h = waitbar(0,'Computing...');
%             ks(L1,M1)
%            Plan_View_Plot
%            close(h)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Main_InterfaceUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {360, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_InterfaceUIFigure = uifigure('Visible', 'off');
            app.Main_InterfaceUIFigure.Name = 'Main_Interface';
            app.Main_InterfaceUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Main_InterfaceUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.GivetheDataFileandInputParametersHereLabel = uilabel(app.GridLayout4);
            app.GivetheDataFileandInputParametersHereLabel.Text = 'Give the Data File and  Input Parameters Here';
            app.ImportDataFilePanel = uipanel(app.GridLayout4);
            app.ImportDataFilePanel.Title = 'Import Data File';
            app.BrowseButton = uibutton(app.ImportDataFilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.InputFileEditFieldLabel = uilabel(app.ImportDataFilePanel);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.ImportDataFilePanel, 'text');
            app.Module1Panel = uipanel(app.GridLayout4);
            app.Module1Panel.Title = 'Module 1';
            app.PEComponentsinTimeseriesButton = uibutton(app.Module1Panel, 'push');
            app.PEComponentsinTimeseriesButton.ButtonPushedFcn = createCallbackFcn(app, @PEComponentsinTimeseriesButtonPushed, true);
            app.PEComponentsinTimeseriesButton.Text = {'PE Components in Timeseries'; ''};
            app.PEComponentsinPlanviewButton = uibutton(app.Module1Panel, 'push');
            app.PEComponentsinPlanviewButton.ButtonPushedFcn = createCallbackFcn(app, @PEComponentsinPlanviewButtonPushed, true);
            app.PEComponentsinPlanviewButton.Text = {'PE Components in Planview'; ''};
            app.Module2Panel = uipanel(app.GridLayout4);
            app.Module2Panel.Title = 'Module 2';
            app.FaultStabilityinPlanviewandPlottingButton = uibutton(app.Module2Panel, 'push');
            app.FaultStabilityinPlanviewandPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @FaultStabilityinPlanviewandPlottingButtonPushed, true);
            app.FaultStabilityinPlanviewandPlottingButton.Text = {'Fault Stability in Planview'; 'and Plotting'; ''};
            app.FaultStabilityinTimeseriesandPlottingButton = uibutton(app.Module2Panel, 'push');
            app.FaultStabilityinTimeseriesandPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @FaultStabilityinTimeseriesandPlottingButtonPushed, true);
            app.FaultStabilityinTimeseriesandPlottingButton.Text = {'Fault Stability in Timeseries'; 'and Plotting'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.HeadingLabel = uilabel(app.GridLayout3);
            app.HeadingLabel.Text = 'RTSeismo';
            app.ResultPanel = uipanel(app.GridLayout3);
            app.ResultPanel.Title = 'Result';
            app.TabGroup = uitabgroup(app.ResultPanel);
            app.stress_pTab = uitab(app.TabGroup);
            app.stress_pTab.Title = 'stress_p';
            app.ppdiffusion_pTab = uitab(app.TabGroup);
            app.ppdiffusion_pTab.Title = 'ppdiffusion_p';
            app.ppstressinduced_pTab = uitab(app.TabGroup);
            app.ppstressinduced_pTab.Title = 'ppstressinduced_p';
            app.stress_tTab = uitab(app.TabGroup);
            app.stress_tTab.Title = 'stress_t';
            app.ppdiffusion_tTab = uitab(app.TabGroup);
            app.ppdiffusion_tTab.Title = 'ppdiffusion_t';
            app.ppstressinduced_tTab = uitab(app.TabGroup);
            app.ppstressinduced_tTab.Title = 'ppstressinduced_t';
            % Show the figure after all components are created
            app.Main_InterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_InterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_InterfaceUIFigure)
        end
    end
end