classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogApp1                      matlab.ui.Figure
        InputArgumentsForPlanviewPanel  matlab.ui.container.Panel
        XobsEditFieldLabel              matlab.ui.control.Label
        XobsEditField                   matlab.ui.control.NumericEditField
        YobsEditFieldLabel              matlab.ui.control.Label
        YobsEditField                   matlab.ui.control.NumericEditField
        XdiscEditFieldLabel             matlab.ui.control.Label
        XdiscEditField                  matlab.ui.control.NumericEditField
        YdiscEditFieldLabel             matlab.ui.control.Label
        YdiscEditField                  matlab.ui.control.NumericEditField
        STRESSButton                    matlab.ui.control.Button
        ntEditFieldLabel                matlab.ui.control.Label
        ntEditField                     matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        MEditFieldLabel                 matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        TdiscEditFieldLabel             matlab.ui.control.Label
        TdiscEditField                  matlab.ui.control.NumericEditField
        SCEditFieldLabel                matlab.ui.control.Label
        SCEditField                     matlab.ui.control.NumericEditField
        DIFFUSIONPPButton               matlab.ui.control.Button
        STRESSINDUCEDPPButton           matlab.ui.control.Button
        DamhmslEditFieldLabel           matlab.ui.control.Label
        DamhmslEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        callingapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_Interface)
           
            app.callingapp=Main_Interface;
           %movegui(app1(app),[300 600]);
        end

        % Button pushed function: STRESSButton
        function STRESSButtonPushed(app, event)
%             app.STRESSButton.Enable = 'off';
          global FNAM;
          global Xobs1;
          global Yobs1;
          global L1;
          global M1;
           L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
           Xdisc1=app.XdiscEditField.Value;
           Ydisc1=app.YdiscEditField.Value;
           nt1=app.ntEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           h = waitbar(0,'Computing...');
           
            [D2]=stress_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1,damhmsl1);
%             [D3]=ppdiff_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
%             [pp]=plc_Test(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
%             [pcc]=ppstressinduced_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
           
           
           [D2] =deal(D2);
           tdata4 = table(D2);
           tdata4
           
           
%            [D3] =deal(D3);
%            tdata5 = table(D3);
%            tdata5
           
%            [pp] =deal(pp);
%            tdata6 = table(pp);
%            tdata6
%            
%            
%            [pcc] =deal(pcc);
%            tdata7 = table(pcc);
%            tdata7
           
           importdata3(app.callingapp,tdata4);
            close(h)
%            delete(app)
        end

        % Button pushed function: DIFFUSIONPPButton
        function DIFFUSIONPPButtonPushed(app, event)
          global FNAM;
          global Xobs1;
          global Yobs1;
           L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
           Xdisc1=app.XdiscEditField.Value;
           Ydisc1=app.YdiscEditField.Value;
           nt1=app.ntEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           h = waitbar(0,'Computing...');
           
%            [D2]=stress_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
             [D3]=ppdiffusion_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1,damhmsl1);
%            [pp]=plc_Test(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
%            [pcc]=ppstressinduced_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
           
           
%            [D2] =deal(D2);
%            tdata4 = table(D2);
%            tdata4
%            
           
           [D3] =deal(D3);
           tdata5 = table(D3);
           tdata5
           
%            [pp] =deal(pp);
%            tdata6 = table(pp);
%            tdata6
%            
%            
%            [pcc] =deal(pcc);
%            tdata7 = table(pcc);
%            tdata7
           
           importdata4(app.callingapp,tdata5);
           close(h)
%            delete(app) 
        end

        % Button pushed function: STRESSINDUCEDPPButton
        function STRESSINDUCEDPPButtonPushed(app, event)
             global FNAM;
             global Xobs1;
             global Yobs1;
           L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
           Xdisc1=app.XdiscEditField.Value;
           Ydisc1=app.YdiscEditField.Value;
           nt1=app.ntEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           h = waitbar(0,'Computing...');
           
%             [D2]=stress_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
%             [D3]=ppdiff_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1);
            [pp]=plc_Test(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1,damhmsl1);
            [pcc]=ppstressinduced_p(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1,nt1,damhmsl1);
           
           
%            [D2] =deal(D2);
%            tdata4 = table(D2);
%            tdata4
%            
%            
%            [D3] =deal(D3);
%            tdata5 = table(D3);
%            tdata5
           
           [pp] =deal(pp);
           tdata6 = table(pp);
           tdata6
           
           
           [pcc] =deal(pcc);
           tdata7 = table(pcc);
           tdata7

           importdata5(app.callingapp,tdata6,tdata7);
           close(h)
           delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogApp1 = uifigure('Visible', 'off');
            app.DialogApp1.Name = 'MATLAB App';
            app.InputArgumentsForPlanviewPanel = uipanel(app.DialogApp1);
            app.InputArgumentsForPlanviewPanel.Title = 'Input Arguments For Planview';
            app.XobsEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.XobsEditFieldLabel.Text = 'Xobs';
            app.XobsEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.YobsEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.YobsEditFieldLabel.Text = 'Yobs';
            app.YobsEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.XdiscEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.XdiscEditFieldLabel.Text = 'Xdisc';
            app.XdiscEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.YdiscEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.YdiscEditFieldLabel.Text = 'Ydisc';
            app.YdiscEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.STRESSButton = uibutton(app.InputArgumentsForPlanviewPanel, 'push');
            app.STRESSButton.ButtonPushedFcn = createCallbackFcn(app, @STRESSButtonPushed, true);
            app.STRESSButton.Text = 'STRESS';
            app.ntEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.ntEditFieldLabel.Text = 'nt';
            app.ntEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.MEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.CEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.TdiscEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.TdiscEditFieldLabel.Text = 'Tdisc';
            app.TdiscEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.SCEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.SCEditFieldLabel.Text = 'SC';
            app.SCEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            app.DIFFUSIONPPButton = uibutton(app.InputArgumentsForPlanviewPanel, 'push');
            app.DIFFUSIONPPButton.ButtonPushedFcn = createCallbackFcn(app, @DIFFUSIONPPButtonPushed, true);
            app.DIFFUSIONPPButton.Text = 'DIFFUSION PP';
            app.STRESSINDUCEDPPButton = uibutton(app.InputArgumentsForPlanviewPanel, 'push');
            app.STRESSINDUCEDPPButton.ButtonPushedFcn = createCallbackFcn(app, @STRESSINDUCEDPPButtonPushed, true);
            app.STRESSINDUCEDPPButton.Text = 'STRESSINDUCED PP';
            app.DamhmslEditFieldLabel = uilabel(app.InputArgumentsForPlanviewPanel);
            app.DamhmslEditFieldLabel.Text = 'Damhmsl';
            app.DamhmslEditField = uieditfield(app.InputArgumentsForPlanviewPanel, 'numeric');
            % Show the figure after all components are created
            app.DialogApp1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogApp1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogApp1)
        end
    end
end