classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogApp2             matlab.ui.Figure
        InputArgumentsForTimeseriesPanel  matlab.ui.container.Panel
        STRESSButton           matlab.ui.control.Button
        XobsEditFieldLabel     matlab.ui.control.Label
        XobsEditField          matlab.ui.control.NumericEditField
        YobsEditFieldLabel     matlab.ui.control.Label
        YobsEditField          matlab.ui.control.NumericEditField
        LEditFieldLabel        matlab.ui.control.Label
        LEditField             matlab.ui.control.NumericEditField
        MEditFieldLabel        matlab.ui.control.Label
        MEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        CEditFieldLabel        matlab.ui.control.Label
        CEditField             matlab.ui.control.NumericEditField
        TdiscEditFieldLabel    matlab.ui.control.Label
        TdiscEditField         matlab.ui.control.NumericEditField
        SCEditFieldLabel       matlab.ui.control.Label
        SCEditField            matlab.ui.control.NumericEditField
        DIFFUSIONPPButton      matlab.ui.control.Button
        STRESSINDUCEDPPButton  matlab.ui.control.Button
        DamhmslEditFieldLabel  matlab.ui.control.Label
        DamhmslEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
         callingapp % Description
    end 
                 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_Interface)
         
          app.callingapp=Main_Interface;  
        end

        % Button pushed function: STRESSButton
        function STRESSButtonPushed(app, event)
             global FNAM;
       
          
           L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
%            Xdisc1=app.XdiscEditField.Value;
%            Ydisc1=app.YdiscEditField.Value;
           
           h = waitbar(0,'Computing...');
           
           [D3]=stress_t(FNAM,L1,M1,Z1,C1,Tdisc1,damhmsl1,SC1,Xobs1,Yobs1);
%            [PP]=ppdiff_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
%            [pp]=plct_Test(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
%            [pcc]=ppstressinduced_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
            
           
           [D3] =deal(D3);
           tdata = table(D3);
           tdata
           
%            
%            [PP] =deal(PP);
%            tdata1 = table(PP);
%            tdata1
%            
%            [pp] =deal(pp);
%            tdata2 = table(pp);
%            tdata2
%            
%            
%            [pcc] =deal(pcc);
%            tdata3 = table(pcc);
%            tdata3
           
%            uit=uitable(app.ComputeplrsTab,'Data',tdata);
%            uit.Position = [40 20 350 450];
%             uit.FontWeight = 'bold';
           importdata(app.callingapp,tdata);
           close(h)
%           delete(app)
        end

        % Button pushed function: DIFFUSIONPPButton
        function DIFFUSIONPPButtonPushed(app, event)
             global FNAM;
       
          
            L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
%            Xdisc1=app.XdiscEditField.Value;
%            Ydisc1=app.YdiscEditField.Value;
           
           h = waitbar(0,'Computing...');
           
%           [D3]=stress_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
           [PP]=ppdiffusion_t(FNAM,L1,M1,Z1,C1,Tdisc1,damhmsl1,SC1,Xobs1,Yobs1);
%          [pp]=plct_Test(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
%           [pcc]=ppstressinduced_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
            
%            
%            [D3] =deal(D3);
%            tdata = table(D3);
%            tdata
           
           
           [PP] =deal(PP);
           tdata1 = table(PP);
           tdata1
%            
%            [pp] =deal(pp);
%            tdata2 = table(pp);
%            tdata2
%            
%            
%            [pcc] =deal(pcc);
%            tdata3 = table(pcc);
%            tdata3
           
%            uit=uitable(app.ComputeplrsTab,'Data',tdata);
%            uit.Position = [40 20 350 450];
%             uit.FontWeight = 'bold';
           importdata1(app.callingapp,tdata1);
           close(h)
%           delete(app)
        end

        % Button pushed function: STRESSINDUCEDPPButton
        function STRESSINDUCEDPPButtonPushed(app, event)
             global FNAM;
       
          
            L1=app.LEditField.Value;
           M1= app.MEditField.Value;
           Z1=app.ZEditField.Value;
           C1=app.CEditField.Value;
           Tdisc1=app.TdiscEditField.Value;
           damhmsl1=app.DamhmslEditField.Value;
           SC1=app.SCEditField.Value;
           Xobs1=app.XobsEditField.Value;
           Yobs1= app.YobsEditField.Value;
%            Xdisc1=app.XdiscEditField.Value;
%            Ydisc1=app.YdiscEditField.Value;
           
           h = waitbar(0,'Computing...');
           
%           [D3]=stress_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
%           [PP]=ppdiff_t(FNAM,L1,M1,Z1,C1,Tdisc1,SC1,Xobs1,Yobs1,Xdisc1,Ydisc1);
           [pp]=plct_Test(FNAM,L1,M1,Z1,C1,Tdisc1,damhmsl1,SC1,Xobs1,Yobs1);
           [pcc]=ppstressinduced_t(FNAM,L1,M1,Z1,C1,Tdisc1,damhmsl1,SC1,Xobs1,Yobs1);
            
           
%            [D3] =deal(D3);
%            tdata = table(D3);
%            tdata
%            
%            
%            [PP] =deal(PP);
%            tdata1 = table(PP);
%            tdata1
           
           [pp] =deal(pp);
           tdata2 = table(pp);
           tdata2
           
           
           [pcc] =deal(pcc);
           tdata3 = table(pcc);
           tdata3
           
%            uit=uitable(app.ComputeplrsTab,'Data',tdata);
%            uit.Position = [40 20 350 450];
%             uit.FontWeight = 'bold';
           importdata2(app.callingapp,tdata2,tdata3);
           close(h)
           delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogApp2 = uifigure('Visible', 'off');
            app.DialogApp2.Name = 'MATLAB App';
            app.InputArgumentsForTimeseriesPanel = uipanel(app.DialogApp2);
            app.InputArgumentsForTimeseriesPanel.Title = 'Input Arguments For Timeseries';
            app.STRESSButton = uibutton(app.InputArgumentsForTimeseriesPanel, 'push');
            app.STRESSButton.ButtonPushedFcn = createCallbackFcn(app, @STRESSButtonPushed, true);
            app.STRESSButton.Text = 'STRESS';
            app.XobsEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.XobsEditFieldLabel.Text = 'Xobs';
            app.XobsEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.YobsEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.YobsEditFieldLabel.Text = 'Yobs';
            app.YobsEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.MEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.CEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.TdiscEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.TdiscEditFieldLabel.Text = 'Tdisc';
            app.TdiscEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.SCEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.SCEditFieldLabel.Text = 'SC';
            app.SCEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            app.DIFFUSIONPPButton = uibutton(app.InputArgumentsForTimeseriesPanel, 'push');
            app.DIFFUSIONPPButton.ButtonPushedFcn = createCallbackFcn(app, @DIFFUSIONPPButtonPushed, true);
            app.DIFFUSIONPPButton.Text = 'DIFFUSION PP';
            app.STRESSINDUCEDPPButton = uibutton(app.InputArgumentsForTimeseriesPanel, 'push');
            app.STRESSINDUCEDPPButton.ButtonPushedFcn = createCallbackFcn(app, @STRESSINDUCEDPPButtonPushed, true);
            app.STRESSINDUCEDPPButton.Text = 'STRESSINDUCED PP';
            app.DamhmslEditFieldLabel = uilabel(app.InputArgumentsForTimeseriesPanel);
            app.DamhmslEditFieldLabel.Text = 'Damhmsl';
            app.DamhmslEditField = uieditfield(app.InputArgumentsForTimeseriesPanel, 'numeric');
            % Show the figure after all components are created
            app.DialogApp2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogApp2)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogApp2)
        end
    end
end