classdef finalapp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ProvideInputParamtersforPlanviewPanel  matlab.ui.container.Panel
        ThEditFieldLabel     matlab.ui.control.Label
        ThEditField          matlab.ui.control.EditField
        ALAMEditFieldLabel   matlab.ui.control.Label
        ALAMEditField        matlab.ui.control.EditField
        ShiEditFieldLabel    matlab.ui.control.Label
        ShiEditField         matlab.ui.control.EditField
        MuEditFieldLabel     matlab.ui.control.Label
        MuEditField          matlab.ui.control.EditField
        OlatEditFieldLabel   matlab.ui.control.Label
        OlatEditField        matlab.ui.control.EditField
        OlonEditFieldLabel   matlab.ui.control.Label
        OlonEditField        matlab.ui.control.EditField
        OlatdEditFieldLabel  matlab.ui.control.Label
        OlatdEditField       matlab.ui.control.EditField
        OlondEditFieldLabel  matlab.ui.control.Label
        OlondEditField       matlab.ui.control.EditField
        PlotButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_Interface)
             app.callingapp=Main_Interface;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global Xobs11;
            global Yobs11;
            global L11;
            global M11;
            Th1=app.ThEditField.Value;
           ALAM1= app.ALAMEditField.Value;
           Shi1=app.ShiEditField.Value;
           Xmu1=app.MuEditField.Value;
           Olat1=app.OlatEditField.Value;
           Olon1=app.OlonEditField.Value;
           Olatd1=app.OlatdEditField.Value;
           Olond1= app.OlondEditField.Value;
           h = waitbar(0,'Computing...');
          FS_p(Th1,ALAM1,Shi1,Xmu1,Olat1,Olon1,Olatd1,Olond1);
          Planview_Plot
           close(h)
           delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProvideInputParamtersforPlanviewPanel = uipanel(app.UIFigure);
            app.ProvideInputParamtersforPlanviewPanel.Title = 'Provide Input Paramters for Planview';
            app.ThEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.ThEditFieldLabel.Text = 'Th';
            app.ThEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.ThEditField.Value = '0';
            app.ALAMEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.ALAMEditFieldLabel.Text = 'ALAM';
            app.ALAMEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.ALAMEditField.Value = '0';
            app.ShiEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.ShiEditFieldLabel.Text = 'Shi';
            app.ShiEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.ShiEditField.Value = '0';
            app.MuEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.MuEditField.Value = '0';
            app.OlatEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.OlatEditFieldLabel.Text = 'Olat';
            app.OlatEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.OlatEditField.Value = '0';
            app.OlonEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.OlonEditFieldLabel.Text = 'Olon';
            app.OlonEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.OlonEditField.Value = '0';
            app.OlatdEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.OlatdEditFieldLabel.Text = 'Olatd';
            app.OlatdEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.OlatdEditField.Value = '0';
            app.OlondEditFieldLabel = uilabel(app.ProvideInputParamtersforPlanviewPanel);
            app.OlondEditFieldLabel.Text = 'Olond';
            app.OlondEditField = uieditfield(app.ProvideInputParamtersforPlanviewPanel, 'text');
            app.OlondEditField.Value = '0';
            app.PlotButton = uibutton(app.ProvideInputParamtersforPlanviewPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end