classdef finalapp4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ProvideInputParamtersforTimeseriesPanel  matlab.ui.container.Panel
        ThEditFieldLabel    matlab.ui.control.Label
        ThEditField         matlab.ui.control.EditField
        ALAMEditFieldLabel  matlab.ui.control.Label
        ALAMEditField       matlab.ui.control.EditField
        ShiEditFieldLabel   matlab.ui.control.Label
        ShiEditField        matlab.ui.control.EditField
        MuEditFieldLabel    matlab.ui.control.Label
        MuEditField         matlab.ui.control.EditField
        PlotButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
       callingapp % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Main_Interface)
             app.callingapp=Main_Interface;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
              Th1=app.ThEditField.Value;
           ALAM1= app.ALAMEditField.Value;
           Shi1=app.ShiEditField.Value;
           Xmu1=app.MuEditField.Value;
           h = waitbar(0,'Computing...');
           FS_t(Th1,ALAM1,Shi1,Xmu1);
           Timeseries_Plot
           close(h)
           delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProvideInputParamtersforTimeseriesPanel = uipanel(app.UIFigure);
            app.ProvideInputParamtersforTimeseriesPanel.Title = 'Provide Input Paramters for Timeseries';
            app.ThEditFieldLabel = uilabel(app.ProvideInputParamtersforTimeseriesPanel);
            app.ThEditFieldLabel.Text = 'Th';
            app.ThEditField = uieditfield(app.ProvideInputParamtersforTimeseriesPanel, 'text');
            app.ThEditField.Value = '0';
            app.ALAMEditFieldLabel = uilabel(app.ProvideInputParamtersforTimeseriesPanel);
            app.ALAMEditFieldLabel.Text = 'ALAM';
            app.ALAMEditField = uieditfield(app.ProvideInputParamtersforTimeseriesPanel, 'text');
            app.ALAMEditField.Value = '0';
            app.ShiEditFieldLabel = uilabel(app.ProvideInputParamtersforTimeseriesPanel);
            app.ShiEditFieldLabel.Text = 'Shi';
            app.ShiEditField = uieditfield(app.ProvideInputParamtersforTimeseriesPanel, 'text');
            app.ShiEditField.Value = '0';
            app.MuEditFieldLabel = uilabel(app.ProvideInputParamtersforTimeseriesPanel);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.ProvideInputParamtersforTimeseriesPanel, 'text');
            app.MuEditField.Value = '0';
            app.PlotButton = uibutton(app.ProvideInputParamtersforTimeseriesPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end